classdef AnalysisTempHyp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        selectfromfigureCheckBox        matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.NumericEditField
        BinningLabel                    matlab.ui.control.Label
        SaturatedpixelsCheckBox         matlab.ui.control.CheckBox
        NotcorrectedforJacobianLabel    matlab.ui.control.Label
        UniformDropDown                 matlab.ui.control.DropDown
        UniformDropDownLabel            matlab.ui.control.Label
        Centrecalculation               matlab.ui.control.DropDown
        CentrecalculationDropDownLabel  matlab.ui.control.Label
        Baseline                        matlab.ui.control.DropDown
        BaselinesubtractionDropDownLabel  matlab.ui.control.Label
        Apexangle                       matlab.ui.control.NumericEditField
        ApexangleEditFieldLabel         matlab.ui.control.Label
        CrystalDropDown                 matlab.ui.control.DropDown
        CrystalLabel                    matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
        LoadsettingsCheckBox            matlab.ui.control.CheckBox
        SpectralpointsNum               matlab.ui.control.NumericEditField
        SpectralpointsEditFieldLabel    matlab.ui.control.Label
        ApodizationFun                  matlab.ui.control.DropDown
        ApodizationDropDownLabel        matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        pol                             matlab.ui.control.CheckBox
        MaxTemp                         matlab.ui.control.NumericEditField
        MinTemp                         matlab.ui.control.NumericEditField
        ZoomCheckBox                    matlab.ui.control.CheckBox
        SelectpixelLabel                matlab.ui.control.Label
        MaxWave                         matlab.ui.control.NumericEditField
        MinWave                         matlab.ui.control.NumericEditField
        GeneratespectralhypercubeButton  matlab.ui.control.Button
        PreviewforselectedpixelLabel    matlab.ui.control.Label
        TemporalhypercubeanalysisLabel  matlab.ui.control.Label
        FrequencycalibrationLabel       matlab.ui.control.Label
        FrequencycalibrationCheckBox    matlab.ui.control.CheckBox
        DelaycorrectionLabel            matlab.ui.control.Label
        DelaycaliabrationCheckBox       matlab.ui.control.CheckBox
        LoadeddatasetLabel              matlab.ui.control.Label
        LoadhypercubeButton             matlab.ui.control.Button
        SpectralhypercubeparametersPanel  matlab.ui.container.Panel
        SavesaturationmapCheckBox       matlab.ui.control.CheckBox
        SpectralpointsLabel             matlab.ui.control.Label
        PolarizersFinal                 matlab.ui.control.Label
        PolarizersLablel                matlab.ui.control.Label
        PixelsFinal                     matlab.ui.control.Label
        SpectralrangeFinal              matlab.ui.control.Label
        TemporalrangeFinal              matlab.ui.control.Label
        CentreFinal                     matlab.ui.control.Label
        BaselineFinal                   matlab.ui.control.Label
        ApodizationFinal                matlab.ui.control.Label
        PixelsLabel                     matlab.ui.control.Label
        SpectralrangeLabel              matlab.ui.control.Label
        TemporalrangeLabel              matlab.ui.control.Label
        CentreLabel                     matlab.ui.control.Label
        BaselineLabel                   matlab.ui.control.Label
        ApodizationLabel                matlab.ui.control.Label
        Savingformat                    matlab.ui.control.DropDown
        SavingformatDropDownLabel       matlab.ui.control.Label
        ValuetypeDropDown               matlab.ui.control.DropDown
        ValuetypeDropDownLabel          matlab.ui.control.Label
        Detector                        matlab.ui.control.DropDown
        DetectorLabel                   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    properties (Access = private)
        HyperMatrix % temporal hypercube
        Hyperspectrum_cube %spectral hypercube
        saturationMap %map of saturated pixels
        dir0 % general directory
        file_tot %directory for temporal hyp
        file_totCal %directory for frequency calibration
        file_totDel %directory for delay correction
        t0 % original delay axis
        tt %corrected delay axis
        f_0 %pseudofrequency in freq correction file
        fr_real0 %optical frequency in freq correction file
        f %pseudofrequency only for hypercube generation
        fr_real %optical frequency only for hypercube generation
        c=299792458; %speed of light
        ti %initial instant index
        tf %final instant index
        Xlimits_zoom; %x axis limits after zooming
        Ylimits_zoom; %y axis limits after zooming
        x = 1; %x coordinate for pixel
        y = 1; %y coordinate for pixel
        crystal = 42; %default crystal alpha-BBO
        pseudo_min = 0.01; %min pseudofrequency- defaul for alpha-BBO, 7°
        pseudo_max = 0.05; %max pseudofrequency- default for alpha-BBO, 7°
        pseudofr %pseudofrequency axis
        pixels_mean=1; %binning
        settings; %settings file
        DetectorCustom; %custom number of bits


    end

    methods (Access = private)

        function save_hypercube(app)
            h=waitbar(0.5,'Saving Hypercube, please wait...');
            tic

            a=size(app.Hyperspectrum_cube,1);
            b=size(app.Hyperspectrum_cube,2);


            Hyperspectrum_cube = app.Hyperspectrum_cube;
            f = app.f;
            fr_real = app.fr_real;
            saturationMap = app.saturationMap;
            file_totCal = app.file_totCal;

            save_format = app.Savingformat.Value;

            switch save_format

                case 1
                    Hyperspectrum_cube=double(Hyperspectrum_cube);
                    fprintf('\n    Save hypercube in .h5, double format \n');
                    if ~isreal(Hyperspectrum_cube)
                        Hyperspectrum_cube_real=real(Hyperspectrum_cube);
                        Hyperspectrum_cube_imag=imag(Hyperspectrum_cube);
                        %create a single images stack Hyperspectrum_cube with imag dataset images
                        %after real dataset images
                        Hyperspectrum_cube=zeros(a,b,size(Hyperspectrum_cube_real,3).*2);
                        Hyperspectrum_cube(:,:,1:size(Hyperspectrum_cube_real,3))=Hyperspectrum_cube_real;
                        Hyperspectrum_cube(:,:,size(Hyperspectrum_cube_real,3)+1:end)=Hyperspectrum_cube_imag;
                        clear Hyperspectrum_cube_real Hyperspectrum_cube_imag
                    end

                case 2
                    Hyperspectrum_cube=single(Hyperspectrum_cube);
                    fprintf('\n    Save hypercube in .h5, single format \n');
                    if ~isreal(Hyperspectrum_cube)
                        Hyperspectrum_cube_real=real(Hyperspectrum_cube);
                        Hyperspectrum_cube_imag=imag(Hyperspectrum_cube);

                        %create a single images stack Hyperspectrum_cube with imag dataset images
                        %after real dataset images
                        Hyperspectrum_cube=single(zeros(a,b,size(Hyperspectrum_cube_real,3).*2));
                        Hyperspectrum_cube(:,:,1:size(Hyperspectrum_cube_real,3))=Hyperspectrum_cube_real;
                        Hyperspectrum_cube(:,:,size(Hyperspectrum_cube_real,3)+1:end)=Hyperspectrum_cube_imag;
                        clear Hyperspectrum_cube_real Hyperspectrum_cube_imag
                    end

                case {3, 6, 7}

                    if save_format == 3
                        fprintf('\n    Save hypercube in .h5, uint16 format \n');
                    elseif save_format == 6
                        fprintf('\n    Save hypercube in .mat, uint16 format \n');
                    else
                        fprintf('\n    Save hypercube in Motion JPEG 2000 Lossless format \n');
                    end

                    if isreal(Hyperspectrum_cube)

                        minimum=min(min(min(real(Hyperspectrum_cube))));
                        Hyperspectrum_cube=Hyperspectrum_cube-minimum; %subtract minumum --> start data from 0
                        maximum=max(max(max(real(Hyperspectrum_cube))));
                        Hyperspectrum_cube=Hyperspectrum_cube./maximum; %divide by maximum --> end data to 1
                        Hyperspectrum_cube=uint16((2.^16-1) * mat2gray(Hyperspectrum_cube,[0 1])); %convert in levels from 0 to 2^16-1

                    else

                        minimum=min([min(min(min(real(Hyperspectrum_cube)))) min(min(min(imag(Hyperspectrum_cube))))]);
                        Hyperspectrum_cube=Hyperspectrum_cube-(1+1i).*minimum; %subtract minumum --> start real and imag data from 0
                        maximum=max([max(max(max(real(Hyperspectrum_cube)))) max(max(max(imag(Hyperspectrum_cube))))]);
                        Hyperspectrum_cube_0_real=real(Hyperspectrum_cube)./maximum; %divide real data by maximum --> end real data to 1
                        Hyperspectrum_cube_1_real=uint16((2.^16-1) * mat2gray(Hyperspectrum_cube_0_real,[0 1])); %convert in levels from 0 to 2^16-1
                        Hyperspectrum_cube_0_imag=imag(Hyperspectrum_cube)./maximum; %divide imag data by maximum --> end imag data to 1
                        Hyperspectrum_cube_1_imag=uint16((2.^16-1) * mat2gray(Hyperspectrum_cube_0_imag,[0 1])); %convert in levels from 0 to 2^16-1
                        clear Hyperspectrum_0_cube_real Hyperspectrum_cube_0_imag

                        %create a single images stack Hyperspectrum_cube with imag dataset images
                        %after real dataset images
                        Hyperspectrum_cube=uint16(zeros(a,b,size(Hyperspectrum_cube_1_real,3).*2));
                        Hyperspectrum_cube(:,:,1:size(Hyperspectrum_cube_1_real,3))=Hyperspectrum_cube_1_real;
                        Hyperspectrum_cube(:,:,size(Hyperspectrum_cube_1_real,3)+1:end)=Hyperspectrum_cube_1_imag;
                        clear Hyperspectrum_1_cube_real Hyperspectrum_cube_1_imag

                    end

                case 4 %.mat double
                    fprintf('\n    Save hypercube in .mat, double format \n');
                    Hyperspectrum_cube=double(Hyperspectrum_cube);

                case 5 %.mat single
                    fprintf('\n    Save hypercube in .mat, single format \n');
                    Hyperspectrum_cube=single(Hyperspectrum_cube);
            end


            %data saving

            if save_format==1 || save_format==2 || save_format==3 %.h5 (double or single or uint16)

                if strcmp(app.file_tot(end-2:end),'.h5') %if format selected is .h5
                    file_spectra=[app.file_tot(1:end-3),'_SpectralHypercube.h5']; %end-3 to exclude '.h5' in the string
                else %if ending part is .mat (this could happen: e.g. in HyperspectralAnalysis_Time where file_tot is referred to the .mat of Temporal Hypercube)
                    file_spectra=[app.file_tot(1:end-4),'_SpectralHypercube.h5']; %end-4 to exclude '.mat' in the string
                end

                %datatype of Hyperspectrum_cube inside H5 file
                if save_format==1
                    datatype='double';
                elseif save_format==2
                    datatype='single';
                else
                    datatype='uint16';
                end

                %delete the previous file if you want to overwrite it
                %necessary to avoid error (normal h5 creation in Matlab does not allow to overwrite files)
                if exist(file_spectra,'file')
                    delete(file_spectra)
                end

                %create variables (datasets) inside the dataset SpectralHypercube
                %inside the H5 file
                h5create(file_spectra,'/SpectralHypercube/Hyperspectrum_cube',[a b size(Hyperspectrum_cube,3)],'Datatype',datatype);
                h5create(file_spectra,'/SpectralHypercube/f',[1 length(f)]);
                h5create(file_spectra,'/SpectralHypercube/saturationMap',[a b]);

                %write variables in H5 file
                h5write(file_spectra,'/SpectralHypercube/Hyperspectrum_cube',Hyperspectrum_cube);
                h5write(file_spectra,'/SpectralHypercube/f',f);
                h5write(file_spectra,'/SpectralHypercube/saturationMap',saturationMap);

                %write other variables in H5 file only if they exist
                write_Str_In_H5(app,file_spectra); %save the calibration string in a dataset inside H5 file

                h5create(file_spectra,'/SpectralHypercube/fr_real',[1 length(fr_real)]);
                h5write(file_spectra,'/SpectralHypercube/fr_real',fr_real);

                if exist('minimum')
                    h5create(file_spectra,'/SpectralHypercube/minimum',1);
                    h5write(file_spectra,'/SpectralHypercube/minimum',minimum);
                end

                if exist('maximum')
                    h5create(file_spectra,'/SpectralHypercube/maximum',1);
                    h5write(file_spectra,'/SpectralHypercube/maximum',maximum);
                end

            elseif save_format==4 || save_format==5 || save_format==6 %.mat (double or single or uint16)

                file_spectra=[app.file_tot(1:end-4),'_SpectralHypercube.mat']; %end-4 to exclude '.mat' in the string

                % Checks the size of the matrix, before saving
                HyProp = whos('Hyperspectrum_cube') ;
                Gigabytes = HyProp.bytes/2^30;

                if exist('minimum') && exist('maximum')
                    if Gigabytes<1.8
                        save(file_spectra,'f','fr_real','Hyperspectrum_cube','saturationMap','file_totCal','minimum','maximum'); %**Benedetto** saturationMap 25/10/2020
                    else
                        save(file_spectra,'f','fr_real','Hyperspectrum_cube','saturationMap','file_totCal','minimum','maximum','-v7.3'); %**Benedetto** saturationMap 25/10/2020
                    end
                else
                    if Gigabytes<1.8
                        save(file_spectra,'f','fr_real','Hyperspectrum_cube','saturationMap','file_totCal'); %**Benedetto** saturationMap 25/10/2020
                    else
                        save(file_spectra,'f','fr_real','Hyperspectrum_cube','saturationMap','file_totCal','-v7.3'); %**Benedetto** saturationMap 25/10/2020
                    end
                end

            end

            tempo_salvataggio=toc;
            waitbar(1,h);
            close(h);

            fprintf('\nTime elapsed for saving: %.1f seconds\n',tempo_salvataggio);

            stringa=[' *** Hypercube saved in ',file_spectra,' ***'];
            fprintf('\n\n%s \n\n',stringa);
        end


        function write_Str_In_H5(app,filename)
            dataset_name = 'file_totCal';
            wdata = app.file_totCal;
            %write 'wdata' string in dataset 'dataset_name' inside H5 file 'filename'
            DIM0 = size(wdata,1);
            SDIM = size(wdata,2)+1;
            dims   = DIM0;
            %Open file using Read/Write option
            file_id = H5F.open(filename,'H5F_ACC_RDWR','H5P_DEFAULT');
            % Preceeding line replaces the H5F create statement in the example:
            %file = H5F.create (filename, 'H5F_ACC_TRUNC', 'H5P_DEFAULT', 'H5P_DEFAULT');
            %Create file and memory datatypes
            filetype = H5T.copy ('H5T_C_S1');
            H5T.set_size (filetype, SDIM-1);
            memtype = H5T.copy ('H5T_C_S1');
            H5T.set_size (memtype, SDIM-1);
            % Create dataspace.  Setting maximum size to [] sets the maximum
            % size to be the current size.
            %
            space_id = H5S.create_simple (1,fliplr( dims), []);
            % Create the dataset and write the string data to it.
            %
            dataset_id = H5D.create (file_id, dataset_name, filetype, space_id, 'H5P_DEFAULT');
            % Transpose the data to match the layout in the H5 file to match C
            % generated H5 file.
            H5D.write (dataset_id, memtype, 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', wdata);
            %Close and release resources
            H5D.close(dataset_id);
            H5S.close(space_id);
            H5T.close(filetype);
            H5T.close(memtype);
            H5F.close(file_id);
        end


        function updateGraphs(app)

            %Plot interferogram
            if mod(app.pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(app.HyperMatrix(app.y-(app.pixels_mean/2-1):app.y+app.pixels_mean/2,app.x-(app.pixels_mean/2-1):app.x+app.pixels_mean/2, app.ti:app.tf),[1,2]));
            else %pixels_mean odd
                interferogram=squeeze(mean(app.HyperMatrix(app.y-floor(app.pixels_mean/2):app.y+floor(app.pixels_mean/2),app.x-floor(app.pixels_mean/2):app.x+floor(app.pixels_mean/2),app.ti:app.tf),[1,2]));
            end
            interferogram = interferogram - mean(interferogram);

            % interferogram=squeeze(app.HyperMatrix(app.y,app.x,app.ti:app.tf))-mean(app.HyperMatrix(app.y,app.x,app.ti:app.tf)); %only oscillating term
            plot(app.UIAxes2, app.ti:app.tf, interferogram); axis(app.UIAxes2, 'tight');
            % plot(app.UIAxes2, app.tt(app.ti:app.tf), interferogram);
            % axis(app.UIAxes2, 'tight'); % to plot motor positions
            if app.Centrecalculation.Value %baricentre
                center=round((1:length(interferogram))*(interferogram.^2)/(sum(interferogram.^2)+1)); % Strategy to find the baricenter (+1 is required to avoid dividing by 0)
            else %midpoipnt
                center=length(interferogram)/2;
            end

            %Plot spectrum
            % pseudofr=linspace(app.pseudo_min,app.pseudo_max, app.SpectralpointsNum.Value);

            spectrum=FourierDir(app.tt(app.ti:app.tf), Apodization(find(strcmp(app.ApodizationFun.Items, app.ApodizationFun.Value)),length(interferogram),center).*interferogram', app.pseudofr);
            if app.FrequencycalibrationCheckBox.Value
                fr_real=interp1(app.f_0, app.fr_real0, app.pseudofr, 'linear', 0);
                wave=app.c./fr_real*10^(-3);
                %Read selected wavelengths
                lambda_min=app.MinWave.Value;
                lambda_max=app.MaxWave.Value;
                idx_wave=wave>lambda_min & wave<lambda_max;
                %Update plot
                % plot(app.UIAxes3, wave(idx_wave), abs(spectrum(idx_wave)))
                % plot(app.UIAxes3, wave, abs(spectrum)); axis(app.UIAxes3, 'tight');
                plot(app.UIAxes3, wave, abs(spectrum)); axis(app.UIAxes3, 'tight');
                xlim(app.UIAxes3, [lambda_min, lambda_max])
                app.UIAxes3.XLabel.String='Wavelength [nm]';
            else
                plot(app.UIAxes3, app.pseudofr, abs(spectrum)); axis(app.UIAxes3, 'tight');
                app.UIAxes3.XLabel.String='Pseudofrequency [um-1]';
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Pre-load dataset
            logo=imread("AnalysisTempHyp_logo.png");
            imagesc(app.UIAxes, logo); colormap(app.UIAxes,"gray"); axis(app.UIAxes, 'tight');

            % we disable the buttons that cannot work without executing the
            % program once first
            app.GeneratespectralhypercubeButton.Enable = "off";

            app.MinWave.Enable='off';
            app.MaxWave.Enable='off';
            %
            % % Desired app size
            % appWidth = 1050;
            % appHeight = 835;
            %
            % % Get screen size
            % screenSize = get(0,'ScreenSize');
            % screenWidth = screenSize(3);
            % screenHeight = screenSize(4);
            %
            % % Calculate center position
            % xPos = (screenWidth - appWidth)/2;
            % yPos = (screenHeight - appHeight)/2;
            %
            % % Set app window position
            % app.UIFigure.Position = [xPos yPos appWidth appHeight];

        end

        % Button pushed function: LoadhypercubeButton
        function LoadhypercubeButtonPushed(app, event)

            [filename_Hyper, pathname_Hyper] = uigetfile({'*.mat';'*.h5*'}, 'Load Hypercube');
            % we re-focus on the main page
            figure(app.UIFigure)

            h = waitbar(0,'Loading Hypercube');
            % For .h5 file: create a .mat file to be loaded after
            if strcmp(filename_Hyper(end-2:end),'.h5')
                H5toMatlabHypercube(filename_Hyper, pathname_Hyper); %creates a .mat in the same folder
                filename_Hyper= strcat(filename_Hyper(1:end-3), '_hyp.mat');
            end

            app.file_tot=[pathname_Hyper,filename_Hyper];
            app.dir0=pathname_Hyper;
            load(app.file_tot);
            app.HyperMatrix=double(HyperMatrix);
            close(h)

            % Update label
            app.PixelsFinal.Text = num2str(size(app.HyperMatrix, 1) * size(app.HyperMatrix, 2));

            app.t0=t; %original delay axis
            app.tt=app.t0; %initialization of the delay axis
            app.ti=1; %initialization of the endpoints
            app.tf=length(app.t0);
            app.MinTemp.Value=app.ti;
            app.MaxTemp.Value=app.tf;

            % Update label
            app.TemporalrangeFinal.Text = strcat(num2str(app.MinTemp.Value), ' - ', num2str(app.MaxTemp.Value));

            app.Xlimits_zoom = [1, size(app.HyperMatrix, 2)+1]; %+1 to preserve hypermatrix dimensions if no zoom is performed
            app.Ylimits_zoom = [1, size(app.HyperMatrix, 1)+1]; %+1 to preserve hypermatrix dimensions if no zoom is performed

            %Update file name
            app.LoadeddatasetLabel.Text=filename_Hyper;

            %Update spectral bands name
            app.SpectralpointsLabel.Text = strcat(num2str(app.SpectralpointsNum.Value), ' bands');

            %Plot average intensity
            Averages=mean(app.HyperMatrix,3);
            imagesc(app.UIAxes, Averages); colormap(app.UIAxes,"parula"); axis(app.UIAxes, 'image');

        end

        % Value changed function: DelaycaliabrationCheckBox
        function DelaycaliabrationCheckBoxValueChanged(app, event)
            value = app.DelaycaliabrationCheckBox.Value;
            if value
                [filename_axis, pathname_axis] = uigetfile('*.mat', 'Load delay correction');
                % we re-focus on the main page
                figure(app.UIFigure)
                file_tot1=[pathname_axis,filename_axis];
                app.file_totDel = file_tot1;

                load(file_tot1);

                %corrected axis
                app.tt=app.t0+interp1(t_axis, t_delayCorrection, app.t0);

                %Update label
                app.DelaycorrectionLabel.Text=filename_axis;

            else
                app.tt=app.t0; %original axis
            end
        end

        % Value changed function: FrequencycalibrationCheckBox
        function FrequencycalibrationCheckBoxValueChanged(app, event)
            if app.FrequencycalibrationCheckBox.Value
                [filename_Cal, pathname_Cal] = uigetfile('*.mat', 'Load Calibration');
                % we re-focus on the main page
                figure(app.UIFigure)
                app.file_totCal=[pathname_Cal,filename_Cal];

                % update label for calibration file
                app.FrequencycalibrationLabel.Text=filename_Cal;

                load(app.file_totCal); % Contains  fr_real0 and f_0

                app.f_0=f_0;
                app.fr_real0=fr_real0;

                %Only with frequency calibration (delay calibration not
                %necessary), you can generate spectral hypercube
                app.GeneratespectralhypercubeButton.Enable='on';
                app.MinWave.Enable='on';
                app.MaxWave.Enable='on';

                % Update label
                app.SpectralrangeFinal.Text = strcat(num2str(app.MinWave.Value), ' - ', num2str(app.MaxWave.Value));

                %Disable crystal/angle properties
                app.CrystalDropDown.Enable = "off";
                app.Apexangle.Enable = "off";

                %Compute min and max pseudofrequencies using the freq
                %calibration file
                app.pseudo_min = min(f_0);
                app.pseudo_max = max(f_0);

                %Compute pseudofrequency axis
                app.pseudofr = linspace(app.pseudo_min,app.pseudo_max, app.SpectralpointsNum.Value);
            end

        end

        % Button pushed function: GeneratespectralhypercubeButton
        function GeneratespectralhypercubeButtonPushed(app, event)

            %HyperMatrix is the final temporal hypercube for the processing
            if app.pol.Value
                HyperMatrix=-app.HyperMatrix(:,:,app.ti:app.tf);
            else
                HyperMatrix=app.HyperMatrix(:,:,app.ti:app.tf);
            end

            a=size(HyperMatrix, 1);
            b=size(HyperMatrix, 2);

            Averages=mean(HyperMatrix, 3);

            for idx=1:size(HyperMatrix, 3)
                HyperMatrix(:,:,idx)=HyperMatrix(:,:,idx)-Averages;
            end
            fprintf('\n\n Debugging: Hypermatrix is %d x %d x %d',a, b, size(HyperMatrix,3));


            % fprintf('\n\n Select sensor dynamics:');
            % n_bit_dynamics=Dinput('\n\n Select number of bits of sensor dynamics:',16);
            n_bit_dynamics = app.Detector.Value;
            if n_bit_dynamics == -1 %Custom number
                n_bit_dynamics = app.DetectorCustom;
            end

            saturationThreshold=2.^(n_bit_dynamics).*(1-0.05); %put the threshold 5% levels below the true saturation level
            clear n_bit_dynamics;

            app.saturationMap=ones(a,b);
            maxHyperMatrix_dynamics=max(abs(HyperMatrix),[],3); %maximum calculated on the time dimension
            app.saturationMap(maxHyperMatrix_dynamics>saturationThreshold)=0; %saturated pixels are mapped with 0s
            if app.SavesaturationmapCheckBox.Value %only if selected, saturated map is saved
                disp('\n\n Saving Saturated pixels map')
                saturationMap = app.saturationMap;
                save(strcat(app.file_tot(1:end-4), '_SaturatedPixelsMap.mat'), 'saturationMap');
            end

            linearFit = app.Baseline.Value;
            trapz=find(strcmp(app.ApodizationFun.Items, app.ApodizationFun.Value));
            center_find = app.Centrecalculation.Value;

            % Saving settings parameter for hypercubes
            settings = struct();
            settings.pol = app.pol.Value;  %polarizer states: 1. crossed polarisers, 0. parallel polarisers
            settings.x_limits = [round(app.Xlimits_zoom(1)),round(app.Xlimits_zoom(2))-1]; %extremes for horizontal axis
            settings.y_limits = [round(app.Ylimits_zoom(1)),round(app.Ylimits_zoom(2))-1]; %extremes for vertical axis
            settings.t_limits = [app.ti, app.tf]; %extreme indexes for time axis

            if app.DelaycaliabrationCheckBox.Value %delay correction loaded
                settings.file_tot_del = app.file_totDel;
            else %no delay correction
                settings.file_tot_del = 'No delay correction';
            end

            if app.FrequencycalibrationCheckBox.Value %freq calibration loaded
                settings.file_tot_cal = app.file_totCal;
            else
                settings.file_tot_cal = 'No frequency correction';
            end

            if ~app.FrequencycalibrationCheckBox.Value
                settings.cryst = app.crystal; %crystal (number for function ncryst)
                settings.apex = app.Apexangle.Value; %apex angle (degrees)
            end
            settings.apod = find(strcmp(app.ApodizationFun.Items, app.ApodizationFun.Value)); %apodization function
            settings.wave_limits = [app.MinWave.Value, app.MaxWave.Value]; %spectral range extremes
            settings.spec_num = app.SpectralpointsNum.Value; %spectral points
            save(strcat(app.file_tot(1:end-4), '_Settings.mat'), 'settings');
            disp('Saving settings')


            %{
            if trapz==6 %**Benedetto**

                auto_max=Dinput('\n    Trapezoidal apodization. Retrieval of maximum: [0: automatic - 1: manual]',0);

                if auto_max==1
                    hh=figure;
                    plot(Corr_summ,'linewidth',2); axis tight;
                    zoom xon; pause;
                    [Center,~]=ginput(1);
                    close(hh);

                    I=round(Center);
                    [maxC,~] = max(C);

                    I1=find(tt-tt(I)<=(tt(I)-tt(1))); % tt(I)-tt(1) is the distance between the first
                    % point and the peak. It will be the distance
                    % between the peak and the last point
                    if length(I1)<3, I1=[1 2 3]; end;

                    tt1=tt(I1); % row

                end;

            end;
            %}

            HyperMatrixFilt1=zeros(a,b);
            tt=app.tt(app.ti:app.tf); %temporal axis for hypercube calculation
            Dt=diff(tt);
            Dt(end+1)=Dt(end);

            %Pseudofrequency and frequency axis
            fr_real_high=app.c/app.MinWave.Value*10^(-3); %maximum optical freq
            fr_real_low=app.c/app.MaxWave.Value*10^(-3); %minimum optical freq
            f_low=interp1(app.fr_real0,app.f_0,fr_real_low); %minumum pseudofr
            f_high=interp1(app.fr_real0,app.f_0,fr_real_high); %maximum pseudofr
            f_points=app.SpectralpointsNum.Value;

            if app.UniformDropDown.Value %uniform in frequency
                app.f=linspace(f_low,f_high,f_points);
                app.fr_real=interp1(app.f_0, app.fr_real0, app.f);
            else %uniform in wavelength
                app.fr_real= app.c./linspace(app.MinWave.Value, app.MaxWave.Value, f_points)*10^3;
                app.f = interp1(app.fr_real0, app.f_0, app.fr_real);
            end


            Df=(f_high-f_low)/(f_points-1);
            Deltaf=(f_high-f_low);

            exps=exp(-1i*2*pi*tt'*app.f);

            h=waitbar(0,'Generation of Hypercube');

            app.Hyperspectrum_cube=zeros(a,b,f_points);

            Amplitude=zeros(a,b);

            tic
            for y=1:a
                waitbar(y/a,h);

                for x=1:b

                    C=squeeze(HyperMatrix(y,x,:))';

                    if linearFit  % Subtracts a line instead of a constant

                        FitPar = polyfit(tt,C,1);

                        FitLinear = polyval(FitPar,tt);
                        C=C-FitLinear;

                    elseif linearFit==2

                        FitPar = polyfit(tt,C,2);

                        FitQuadr = polyval(FitPar,tt);
                        C=C-FitQuadr;
                    end


                    if trapz==6 %the CASE OF TRAPEZOIDAL apodization is treated independently **Benedetto**

                        if auto_max==1

                            [maxC,~] = max(C);

                            %I, I1, tt1: calculated before
                        else

                            %I, I1, tt1: calculated for any time
                            [maxC,I] = max(C); % finds the position of the max

                            I1=find(tt-tt(I)<=(tt(I)-tt(1))); % tt(I)-tt(1) is the distance between the first
                            % point and the peak. It will be the distance
                            % between the peak and the last point
                            if length(I1)<3, I1=[1 2 3]; end;

                            tt1=tt(I1); % row

                        end
                        y1=C(I1); % I select only the symmetric correlation

                        minC = min(C); % finds the min

                        maxC=maxC+Averages(y,x);
                        minC=minC+Averages(y,x);

                        Dt_1=diff(tt1);
                        Dt_1(end+1)=Dt_1(end);

                        exps_1=exp(-1i*2*pi*tt1'*app.f);

                        s_1=y1.*Apodization(4,length(y1),I); % row

                        % fourier computed with the exponential matrix and no loop involved
                        yf_1=(Dt_1.*s_1)*exps_1;

                        % double Trapezoidal filter
                        linear=1/(tt1(end)-tt1(1))*(tt1-tt1(1));
                        Trap_l=ones(size(C));
                        Trap_l(1:length(tt1))=linear;

                        Trap_l(end:-1:end-length(tt1)+1)=linear; % Trapezoidal apodization


                        yf_ok=(Dt.*(C.*Trap_l))*exps;
                        Spectrum=2*yf_ok;

                        app.Hyperspectrum_cube(y,x,:)=Spectrum;


                        HyperMatrixFilt1(y,x)=sum(Spectrum).*Df; % Integral of filtered spectrum
                        Normalization=Averages(y,x)./HyperMatrixFilt1(y,x);


                    else %the OTHER CASES of apodization are treated here

                        if center_find % **Benedetto** 14/01/2021
                            I=round([1:length(C)]*(C.^2)'./(sum(C.^2)+1)); % Strategy to find the baricenter (+1 is required to avoid dividing by 0)
                        else
                            I=length(C)./2;
                        end

                        if trapz==0 %if there's no apodization
                            Trap_1=ones(1,length(C));
                            s_0=C; %row

                        else %if there's apodization different from the trapezoidal one
                            Trap_1=Apodization(trapz,length(C),I,7);
                            s_0=C.*Trap_1; % row

                        end

                        % fourier computed with the exponential matrix and no loop involved
                        yf_0=(Dt.*s_0)*exps;

                        Spectrum=2*yf_0;

                        app.Hyperspectrum_cube(y,x,:)=Spectrum;

                        HyperMatrixFilt1(y,x)=sum(Spectrum).*Df; % Integral of filtered spectrum
                        Normalization=Averages(y,x)./HyperMatrixFilt1(y,x);


                    end


                end

            end
            toc

            close(h);

            ValueFormat = app.ValuetypeDropDown.Value;

            if ValueFormat
                app.Hyperspectrum_cube=abs(app.Hyperspectrum_cube);
            end

            %Saving hypercube
            save_hypercube(app)

            delete(app.UIFigure);

        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            % Check for limits
            if app.xEditField.Value>0 & app.xEditField.Value<=size(app.HyperMatrix, 2) & (fix(app.xEditField.Value)==app.xEditField.Value)
                app.x = app.xEditField.Value;
                updateGraphs(app);
            else
                uialert(app.UIFigure, 'Invalid value. Please check your input.','Warning', 'Icon', 'warning');
            end


        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            % Check for limits
            if app.yEditField.Value>0 & app.yEditField.Value<=size(app.HyperMatrix, 1)
                app.y = app.yEditField.Value;
                updateGraphs(app);
            else
                uialert(app.UIFigure, 'Invalid value. Please check your input.','Warning', 'Icon', 'warning');
            end


        end

        % Value changed function: MinWave
        function MinWaveValueChanged(app, event)
            % Update label
            app.SpectralrangeFinal.Text = strcat(num2str(app.MinWave.Value), ' - ', num2str(app.MaxWave.Value));
            updateGraphs(app);

        end

        % Value changed function: MaxWave
        function MaxWaveValueChanged(app, event)
            % Update label
            app.SpectralrangeFinal.Text = strcat(num2str(app.MinWave.Value), ' - ', num2str(app.MaxWave.Value));
            updateGraphs(app);

        end

        % Value changed function: SpectralpointsNum
        function SpectralpointsNumValueChanged(app, event)
            % Update label
            app.SpectralpointsLabel.Text = strcat(num2str(app.SpectralpointsNum.Value), ' bands');

        end

        % Value changed function: ApodizationFun
        function ApodizationFunValueChanged(app, event)
            % Update apodization label
            app.ApodizationFinal.Text = app.ApodizationFun.Value;

        end

        % Value changed function: LoadsettingsCheckBox
        function LoadsettingsCheckBoxValueChanged(app, event)

            if app.LoadsettingsCheckBox.Value == 1
                % Settings.mat includes
                % - temporal axis indexes [t_min, t_max]
                % - apodization function [apod]
                % - spectral axis limits [lambda_min, lambda_max]
                % - number of spectral points [spec_num]
                % - frequency calibration file directory [file_tot_cal]
                % - delay axis calibration file directory [file_tot_del]
                % - polarization state [pol]
                % - crystal [cryst]
                % - apex angle [apexAngle]
                [filename_sett, pathname_sett] = uigetfile('*.mat', 'Load Settings');
                % we re-focus on the main page
                figure(app.UIFigure)

                file_tot_sett=[pathname_sett, filename_sett];
                file = load(file_tot_sett);
                settings = file.settings;

                % Update label
                app.SettingsLabel.Text=filename_sett;

                %Set values
                if isfield(settings, 't_limits')
                    app.ti=settings.t_limits(1);
                    app.tf=settings.t_limits(2);

                    fprintf('\n Temporal axis limits loaded');
                    app.MinTemp.Value = app.ti; %gui updated
                    app.MaxTemp.Value = app.tf; %gui updated
                    % Update label
                    app.TemporalrangeFinal.Text = strcat(num2str(app.MinTemp.Value), ' - ', num2str(app.MaxTemp.Value));
                end

                if isfield(settings, 'apod')
                    app.ApodizationFun.Value = app.ApodizationFun.Items(settings.apod);
                    % Update apodization label
                    app.ApodizationFinal.Text = app.ApodizationFun.Value;
                    fprintf('\n Apodization function loaded');
                end

                if isfield(settings, 'cryst')
                    app.crystal = settings.cryst;
                    fprintf('\n Crystal loaded');
                    switch app.crystal %gui updated
                        case 42
                            app.CrystalDropDown.Value = 'Alpha BBO';
                            % app.CrystalDropDown.ValueIndex = 1;
                        case 23
                            app.CrystalDropDown.Value ='YVO4';
                            % app.CrystalDropDown.ValueIndex = 2;
                        case 47
                            app.CrystalDropDown.Value = 'Calomel';
                            % app.CrystalDropDown.ValueIndex = 3;
                    end

                end


                if isfield(settings, 'apex')
                    app.Apexangle.Value = settings.apex;
                    fprintf('\n Apex angle loaded');
                end

                CrystalDropDownValueChanged(app, event) %pseudomin, pseudomax for whole working range

                if isfield(settings, 'wave_limits')
                    app.MinWave.Value = settings.wave_limits(1);
                    app.MaxWave.Value = settings.wave_limits(2);
                    fprintf('\n Wavelength axis loaded');
                end

                if isfield(settings, 'spec_num')
                    app.SpectralpointsNum.Value = settings.spec_num;
                    fprintf('\n NUmber of spectral points loaded');
                end

                if isfield(settings, 'pol')
                    app.pol.Value = settings.pol;
                    if app.pol.Value == 1
                        app.PolarizersFinal.Text = 'Crossed';
                    else
                        app.PolarizersFinal.Text = 'Parallel';
                    end
                    fprintf('\n Polarizers state loaded');
                end

                if isfield(settings, 'file_tot_del')
                    if ~ strcmp(settings.file_tot_del, 'No delay correction') %delay correction loaded
                        load(settings.file_tot_del)
                        app.DelaycorrectionLabel.Text=settings.file_tot_del;
                        app.DelaycaliabrationCheckBox.Value = 1; %checkbox checked

                        %corrected axis
                        app.tt=app.t0+interp1(t_axis, t_delayCorrection, app.t0);

                        app.DelaycaliabrationCheckBox.Value = 1;
                        app.DelaycorrectionLabel.Text = settings.file_tot_del;
                        app.file_totDel = settings.file_tot_del;
                        fprintf('\n Delay correction loaded');

                    end
                end
                if isfield(settings, 'file_tot_cal')
                    if ~ strcmp(settings.file_tot_cal,'No frequency correction') %frequency correction loaded
                        load(settings.file_tot_cal)
                        app.FrequencycalibrationLabel.Text=settings.file_tot_cal;
                        app.FrequencycalibrationCheckBox.Value = 1; %checkbox checked
                        app.Apexangle.Enable = 'off';
                        app.CrystalDropDown.Enable = "off";
                        app.f_0=f_0;
                        app.fr_real0=fr_real0;
                        app.file_totCal=settings.file_tot_cal;

                        % update label for calibration file
                        app.FrequencycalibrationLabel.Text=settings.file_tot_cal;

                        app.FrequencycalibrationCheckBox.Value= 1;
                        app.GeneratespectralhypercubeButton.Enable='on';
                        app.MinWave.Enable='on';
                        app.MaxWave.Enable='on';app.f_0=f_0;

                        % Update label
                        app.SpectralrangeFinal.Text = strcat(num2str(app.MinWave.Value), ' - ', num2str(app.MaxWave.Value));

                        %Disable crystal/angle properties
                        app.CrystalDropDown.Enable = "off";
                        app.Apexangle.Enable = "off";

                        %Compute min and max pseudofrequencies using the freq
                        %calibration file
                        app.pseudo_min = min(f_0);
                        app.pseudo_max = max(f_0);

                        %Compute pseudofrequency axis
                        app.pseudofr = linspace(app.pseudo_min,app.pseudo_max, app.SpectralpointsNum.Value);

                        fprintf('\n Frequency calibration loaded');
                    end
                end


            end


        end

        % Value changed function: ZoomCheckBox
        function ZoomCheckBoxValueChanged(app, event)

            % disp('Debugging: Zoom and press ENTER. Only zoomed area will be transformed');
            % zoom(app.UIAxes, 'on');
            % pause();
            % app.Xlimits_zoom=app.UIAxes.XLim;
            % app.Ylimits_zoom=app.UIAxes.YLim;
            % xmin=round(app.Xlimits_zoom(1)); xmax=round(app.Xlimits_zoom(2))-1; % First and last row
            % ymin=round(app.Ylimits_zoom(1)); ymax=round(app.Ylimits_zoom(2))-1; % First and last column
            if app.ZoomCheckBox.Value
                figZoom = figure();
                figZoomAx = gca;
                imagesc(figZoomAx, mean(app.HyperMatrix,3)); colormap(figZoomAx, 'parula'); axis(figZoomAx, 'image');
                figZoomAx.Title.String = 'Zoom';
                buttonOn = uicontrol(figZoom,'String', 'Crop','Callback', @(src,evt) uiresume(figZoom));
                zoom(figZoom, 'on');
                uiwait(figZoom);

                xmin= max(1, round(figZoomAx.XLim(1))); xmax= min(size(app.HyperMatrix, 2), round(figZoomAx.XLim(2))-1); % First and last row
                ymin= max(1, round(figZoomAx.YLim(1))); ymax= min(size(app.HyperMatrix, 1), round(figZoomAx.YLim(2))-1); % First and last column
                close(figZoom);
                app.HyperMatrix=app.HyperMatrix(ymin:ymax,xmin:xmax, :);

                app.Xlimits_zoom=[xmin, xmax];
                app.Ylimits_zoom=[ymin, ymax];
                % Update label
                app.PixelsFinal.Text = num2str(size(app.HyperMatrix, 1) * size(app.HyperMatrix, 2));

                %Plot average intensity with proper axes
                Averages=mean(app.HyperMatrix,3);
                imagesc(app.UIAxes, Averages); colormap(app.UIAxes,"parula"); axis(app.UIAxes, 'tight');

                fprintf(['\n  -- Number of selected pixels: ',num2str(size(app.HyperMatrix, 1)),' x ',num2str(size(app.HyperMatrix, 2))]);
                zoom(app.UIAxes, 'off');
            end

        end

        % Value changed function: MinTemp
        function MinTempValueChanged(app, event)
            valueMinT = app.MinTemp.Value;
            if fix(valueMinT)==valueMinT & valueMinT>=1 & valueMinT<app.tf
                app.ti=valueMinT;
                % Update label
                app.TemporalrangeFinal.Text = strcat(num2str(app.MinTemp.Value), ' - ', num2str(app.MaxTemp.Value));

                updateGraphs(app);
            else
                uialert(app.UIFigure, 'Invalid value. Please check your input.','Warning', 'Icon', 'warning');
            end

        end

        % Value changed function: MaxTemp
        function MaxTempValueChanged(app, event)
            valueMaxT = app.MaxTemp.Value;
            if fix(valueMaxT)==valueMaxT & valueMaxT<=length(app.t0) & valueMaxT> app.ti
                app.tf=valueMaxT;
                % Update label
                app.TemporalrangeFinal.Text = strcat(num2str(app.MinTemp.Value), ' - ', num2str(app.MaxTemp.Value));

                updateGraphs(app);
            else
                uialert(app.UIFigure, 'Invalid value. Please check your input.','Warning', 'Icon', 'warning');

            end

        end

        % Value changed function: CrystalDropDown
        function CrystalDropDownValueChanged(app, event)

            % cryst = app.CrystalDropDown.ValueIndex; %1- alpha BBO; 2- YVO4; 3-Calomel
            cryst = find(strcmp(app.CrystalDropDown.Items, app.CrystalDropDown.Value));
            switch cryst
                case 1 % alpha BBO: 400-900 nm
                    app.crystal = 42;
                    app.MinWave.Value = 350;
                    app.MaxWave.Value = 1100;
                case 2 % YVO4: 400-900 nm
                    app.crystal= 23;
                    app.MinWave.Value = 350;
                    app.MaxWave.Value = 1100;
                case 3 % Calomel: 5000-15000 nm
                    app.crystal = 47;
                    app.MinWave.Value = 5000;
                    app.MaxWave.Value = 15000;
            end
            app.pseudo_min = abs(diff(nCryst(app.crystal,app.MaxWave.Value*1e-3,23))*sin(app.Apexangle.Value/180*pi)/ app.MaxWave.Value*10^3);
            app.pseudo_max = abs(diff(nCryst(app.crystal,app.MinWave.Value*1e-3,23))*sin(app.Apexangle.Value/180*pi)/ app.MinWave.Value*10^3);


        end

        % Value changed function: Apexangle
        function ApexangleValueChanged(app, event)

            app.pseudo_min = abs(diff(nCryst(app.crystal,app.MaxWave.Value*1e-3,23))*sin(app.Apexangle.Value/180*pi)/ app.MaxWave.Value*10^3);
            app.pseudo_max = abs(diff(nCryst(app.crystal,app.MinWave.Value*1e-3,23))*sin(app.Apexangle.Value/180*pi)/ app.MinWave.Value*10^3);

        end

        % Value changed function: Baseline
        function BaselineValueChanged(app, event)
            app.BaselineFinal.Text = app.Baseline.Items(find(app.Baseline.ItemsData==app.Baseline.Value));
        end

        % Value changed function: Centrecalculation
        function CentrecalculationValueChanged(app, event)
            app.CentreFinal.Text = app.Centrecalculation.Items(find(app.Centrecalculation.ItemsData== app.Centrecalculation.Value));
            updateGraphs(app);

        end

        % Value changed function: pol
        function polValueChanged(app, event)
            if app.pol.Value == 1
                app.PolarizersFinal.Text = 'Crossed';
            else
                app.PolarizersFinal.Text = 'Parallel';
            end

        end

        % Value changed function: SaturatedpixelsCheckBox
        function SaturatedpixelsCheckBoxValueChanged(app, event)

            if app.SaturatedpixelsCheckBox.Value
                n_bit_dynamics = app.Detector.Value;
                if n_bit_dynamics == -1
                    n_bit_dynamics = app.DetectorCustom;
                end

                saturationThreshold = 2.^(n_bit_dynamics).*(1-0.05); %put the threshold 5% levels below the true saturation level
                clear n_bit_dynamics;
                saturationMap = ones(size(app.HyperMatrix, 1), size(app.HyperMatrix, 2));
                maxHyperMatrix_dynamics = max(abs(app.HyperMatrix),[],3); %maximum calculated on the time dimension
                saturationMap(maxHyperMatrix_dynamics>saturationThreshold) = 0;

                figSat = figure();
                figSatAx = gca;
                if app.pol.Value
                    imagesc(figSatAx, mean(-app.HyperMatrix, 3).*saturationMap);
                else
                    imagesc(figSatAx, mean(app.HyperMatrix, 3).*saturationMap);
                end
                axis image;
                % Grey colormap with zero pixels red
                M=linspace(0,1, 256)';
                MAP=[M M M];
                MAP(1,:)=[1 0 0];
                colormap(MAP);

                figSatAx.Title.String = 'Saturated pixels';
            end

        end

        % Value changed function: UniformDropDown
        function UniformDropDownValueChanged(app, event)

            if app.UniformDropDown.Value %uniform in frequency
                app.pseudofr = linspace(app.pseudo_min,app.pseudo_max, app.SpectralpointsNum.Value);

            else %uniform in wavelength
                %lambda is proportional to 1/pseudofr
                pseudoLambda_min = 1/app.pseudo_max;
                pseudoLambda_max = 1/app.pseudo_min;
                pseudoLambda = linspace(pseudoLambda_min, pseudoLambda_max, app.SpectralpointsNum.Value); %equispaced
                app.pseudofr = 1./pseudoLambda;
            end

            updateGraphs(app);

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.pixels_mean = app.EditField.Value;

            if app.pixels_mean<1
                app.pixels_mean=1;
            end

            if app.y+floor(app.pixels_mean./2)>size(app.HyperMatrix,1)
                app.pixels_mean=2.*(size(app.HyperMatrix,1)-app.y)+1; %the pixel mean square cannot overcome the lower image limit
            end

            if app.y-floor(app.pixels_mean./2)<1
                app.pixels_mean=2.*app.y-1; %the pixel mean square cannot overcome the upper image limit
            end

            if app.x+floor(app.pixels_mean./2)>size(app.HyperMatrix,2)
                app.pixels_mean=2.*(size(app.HyperMatrix,2)-app.x)+1; %the pixel mean square cannot overcome the right image limit
            end

            if app.x-floor(app.pixels_mean./2)<1
                app.pixels_mean=2.*app.x-1; %the pixel mean square cannot overcome the left image limit
            end

            updateGraphs(app);

        end

        % Value changed function: selectfromfigureCheckBox
        function selectfromfigureCheckBoxValueChanged(app, event)
            if app.selectfromfigureCheckBox.Value

                figPixel = figure();
                figPixelAx = gca;
                imagesc(figPixelAx, mean(app.HyperMatrix,3)); colormap(figPixelAx, 'parula'); axis(figPixelAx, 'image');
                figPixelAx.Title.String = 'Select pixel';
                [app.x, app.y] = ginput(1);
                close(figPixel);
                updateGraphs(app);

                %Remove selection to allow new selections
                app.selectfromfigureCheckBox.Value = 0;
            end


        end

        % Value changed function: Detector
        function DetectorValueChanged(app, event)
            if app.Detector.Value == -1 %custom #bits
                answer = inputdlg('Enter a number:', 'Input Required', [1 35]);

                if ~isempty(answer)
                    app.DetectorCustom = str2double(answer{1});

                    if isnan(app.DetectorCustom)
                        uialert(app.UIFigure, 'Please enter a valid number.', 'Invalid Input');
                    end
                end

            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Spectrum')
            xlabel(app.UIAxes3, 'Pseudofrequency')
            ylabel(app.UIAxes3, 'Intensity')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Interferogram')
            xlabel(app.UIAxes2, 'Motor position [\mum]')
            ylabel(app.UIAxes2, 'Intensity')
            zlabel(app.UIAxes2, 'Z')
            app.SpectralhypercubeparametersPanel = uipanel(app.UIFigure);
            app.SpectralhypercubeparametersPanel.Title = 'Spectral hypercube parameters';
            app.DetectorLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.DetectorLabel.Text = 'Detector';
            app.Detector = uidropdown(app.SpectralhypercubeparametersPanel);
            app.Detector.Items = {'12 bits', '14 bits', '16 bits', 'Custom'};
            app.Detector.ItemsData = [12 14 16 -1];
            app.Detector.ValueChangedFcn = createCallbackFcn(app, @DetectorValueChanged, true);
            app.Detector.Value = 12;
            app.ValuetypeDropDownLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.ValuetypeDropDownLabel.Text = 'Value type';
            app.ValuetypeDropDown = uidropdown(app.SpectralhypercubeparametersPanel);
            app.ValuetypeDropDown.Items = {'Absolute values', 'Complex values'};
            app.ValuetypeDropDown.ItemsData = [1 0];
            app.ValuetypeDropDown.Value = 0;
            app.SavingformatDropDownLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.SavingformatDropDownLabel.Text = 'Saving format';
            app.Savingformat = uidropdown(app.SpectralhypercubeparametersPanel);
            app.Savingformat.Items = {'.h5 double (64 bit)', '.h5 single (32 bit)', '.h5 uint16 (16 bit)', '.mat double (64 bit)', '.mat single (32 bit)', '.mat uint16 (16 bit)'};
            app.Savingformat.ItemsData = [1 2 3 4 5 6];
            app.Savingformat.Value = 5;
            app.ApodizationLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.ApodizationLabel.Text = 'Apodization';
            app.BaselineLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.BaselineLabel.Text = 'Baseline';
            app.CentreLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.CentreLabel.Text = 'Centre';
            app.TemporalrangeLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.TemporalrangeLabel.Text = 'Temporal range';
            app.SpectralrangeLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.SpectralrangeLabel.Text = 'Spectral range';
            app.PixelsLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.PixelsLabel.Text = 'Pixels';
            app.ApodizationFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.ApodizationFinal.Text = 'Happ-Genzel';
            app.BaselineFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.BaselineFinal.Text = 'Constant line';
            app.CentreFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.CentreFinal.Text = 'Baricentre';
            app.TemporalrangeFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.TemporalrangeFinal.Text = 'Temporal range';
            app.SpectralrangeFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.SpectralrangeFinal.Text = 'Spectral range';
            app.PixelsFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.PixelsFinal.Text = 'Pixels';
            app.PolarizersLablel = uilabel(app.SpectralhypercubeparametersPanel);
            app.PolarizersLablel.Text = 'Polarizers';
            app.PolarizersFinal = uilabel(app.SpectralhypercubeparametersPanel);
            app.PolarizersFinal.Text = 'Parallel';
            app.SpectralpointsLabel = uilabel(app.SpectralhypercubeparametersPanel);
            app.SpectralpointsLabel.Text = 'Spectral points';
            app.SavesaturationmapCheckBox = uicheckbox(app.SpectralhypercubeparametersPanel);
            app.SavesaturationmapCheckBox.Text = 'Save saturation map';
            app.LoadhypercubeButton = uibutton(app.UIFigure, 'push');
            app.LoadhypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadhypercubeButtonPushed, true);
            app.LoadhypercubeButton.Text = 'Load hypercube';
            app.LoadeddatasetLabel = uilabel(app.UIFigure);
            app.LoadeddatasetLabel.Text = 'Loaded dataset';
            app.DelaycaliabrationCheckBox = uicheckbox(app.UIFigure);
            app.DelaycaliabrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelaycaliabrationCheckBoxValueChanged, true);
            app.DelaycaliabrationCheckBox.Text = 'Delay caliabration';
            app.DelaycorrectionLabel = uilabel(app.UIFigure);
            app.DelaycorrectionLabel.Text = 'Delay correction';
            app.FrequencycalibrationCheckBox = uicheckbox(app.UIFigure);
            app.FrequencycalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrequencycalibrationCheckBoxValueChanged, true);
            app.FrequencycalibrationCheckBox.Text = 'Frequency calibration';
            app.FrequencycalibrationLabel = uilabel(app.UIFigure);
            app.FrequencycalibrationLabel.Text = 'Frequency calibration';
            app.TemporalhypercubeanalysisLabel = uilabel(app.UIFigure);
            app.TemporalhypercubeanalysisLabel.Text = 'Temporal hypercube analysis';
            app.PreviewforselectedpixelLabel = uilabel(app.UIFigure);
            app.PreviewforselectedpixelLabel.Text = 'Preview for selected pixel';
            app.GeneratespectralhypercubeButton = uibutton(app.UIFigure, 'push');
            app.GeneratespectralhypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratespectralhypercubeButtonPushed, true);
            app.GeneratespectralhypercubeButton.Text = 'Generate spectral hypercube';
            app.MinWave = uieditfield(app.UIFigure, 'numeric');
            app.MinWave.ValueChangedFcn = createCallbackFcn(app, @MinWaveValueChanged, true);
            app.MinWave.Value = 400;
            app.MaxWave = uieditfield(app.UIFigure, 'numeric');
            app.MaxWave.ValueChangedFcn = createCallbackFcn(app, @MaxWaveValueChanged, true);
            app.MaxWave.Value = 900;
            app.SelectpixelLabel = uilabel(app.UIFigure);
            app.SelectpixelLabel.Text = 'Select pixel';
            app.ZoomCheckBox = uicheckbox(app.UIFigure);
            app.ZoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomCheckBoxValueChanged, true);
            app.ZoomCheckBox.Text = 'Zoom';
            app.MinTemp = uieditfield(app.UIFigure, 'numeric');
            app.MinTemp.ValueChangedFcn = createCallbackFcn(app, @MinTempValueChanged, true);
            app.MaxTemp = uieditfield(app.UIFigure, 'numeric');
            app.MaxTemp.ValueChangedFcn = createCallbackFcn(app, @MaxTempValueChanged, true);
            app.MaxTemp.Value = 1;
            app.pol = uicheckbox(app.UIFigure);
            app.pol.ValueChangedFcn = createCallbackFcn(app, @polValueChanged, true);
            app.pol.Text = 'Crossed polarizers';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.yEditField.Value = 1;
            app.ApodizationDropDownLabel = uilabel(app.UIFigure);
            app.ApodizationDropDownLabel.Text = 'Apodization';
            app.ApodizationFun = uidropdown(app.UIFigure);
            app.ApodizationFun.Items = {'Happ-Genzel', '3-term Blackman-Harris', '4-term Blackman-Harris', 'Triangular', 'Supergaussian', 'Trapezoidal'};
            app.ApodizationFun.ValueChangedFcn = createCallbackFcn(app, @ApodizationFunValueChanged, true);
            app.ApodizationFun.Value = 'Happ-Genzel';
            app.SpectralpointsEditFieldLabel = uilabel(app.UIFigure);
            app.SpectralpointsEditFieldLabel.Text = 'Spectral points';
            app.SpectralpointsNum = uieditfield(app.UIFigure, 'numeric');
            app.SpectralpointsNum.ValueChangedFcn = createCallbackFcn(app, @SpectralpointsNumValueChanged, true);
            app.SpectralpointsNum.Value = 300;
            app.LoadsettingsCheckBox = uicheckbox(app.UIFigure);
            app.LoadsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadsettingsCheckBoxValueChanged, true);
            app.LoadsettingsCheckBox.Text = 'Load settings';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.CrystalLabel = uilabel(app.UIFigure);
            app.CrystalLabel.Text = 'Crystal';
            app.CrystalDropDown = uidropdown(app.UIFigure);
            app.CrystalDropDown.Items = {'Alpha BBO', 'YVO4', 'Calomel'};
            app.CrystalDropDown.ValueChangedFcn = createCallbackFcn(app, @CrystalDropDownValueChanged, true);
            app.CrystalDropDown.Value = 'Alpha BBO';
            app.ApexangleEditFieldLabel = uilabel(app.UIFigure);
            app.ApexangleEditFieldLabel.Text = 'Apex angle';
            app.Apexangle = uieditfield(app.UIFigure, 'numeric');
            app.Apexangle.ValueChangedFcn = createCallbackFcn(app, @ApexangleValueChanged, true);
            app.Apexangle.Value = 7;
            app.BaselinesubtractionDropDownLabel = uilabel(app.UIFigure);
            app.BaselinesubtractionDropDownLabel.Text = 'Baseline subtraction';
            app.Baseline = uidropdown(app.UIFigure);
            app.Baseline.Items = {'Constant line', 'Linear trend', 'Quadratic trend'};
            app.Baseline.ItemsData = [0 1 2];
            app.Baseline.ValueChangedFcn = createCallbackFcn(app, @BaselineValueChanged, true);
            app.Baseline.Value = 0;
            app.CentrecalculationDropDownLabel = uilabel(app.UIFigure);
            app.CentrecalculationDropDownLabel.Text = 'Centre calculation';
            app.Centrecalculation = uidropdown(app.UIFigure);
            app.Centrecalculation.Items = {'Midpoint', 'Baricentre'};
            app.Centrecalculation.ItemsData = [0 1];
            app.Centrecalculation.ValueChangedFcn = createCallbackFcn(app, @CentrecalculationValueChanged, true);
            app.Centrecalculation.Value = 1;
            app.UniformDropDownLabel = uilabel(app.UIFigure);
            app.UniformDropDownLabel.Text = 'Uniform';
            app.UniformDropDown = uidropdown(app.UIFigure);
            app.UniformDropDown.Items = {'Frequency', 'Wavelength'};
            app.UniformDropDown.ItemsData = [1 0];
            app.UniformDropDown.ValueChangedFcn = createCallbackFcn(app, @UniformDropDownValueChanged, true);
            app.UniformDropDown.Value = 1;
            app.NotcorrectedforJacobianLabel = uilabel(app.UIFigure);
            app.NotcorrectedforJacobianLabel.Text = 'Not corrected for Jacobian';
            app.SaturatedpixelsCheckBox = uicheckbox(app.UIFigure);
            app.SaturatedpixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaturatedpixelsCheckBoxValueChanged, true);
            app.SaturatedpixelsCheckBox.Text = 'Saturated pixels';
            app.BinningLabel = uilabel(app.UIFigure);
            app.BinningLabel.Text = 'Binning';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.selectfromfigureCheckBox = uicheckbox(app.UIFigure);
            app.selectfromfigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectfromfigureCheckBoxValueChanged, true);
            app.selectfromfigureCheckBox.Text = 'select from figure';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SpectralpointsLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisTempHyp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end