classdef WConversion_cryst_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        text3_2             matlab.ui.control.Label
        WedgeNum            matlab.ui.control.DropDown
        apodizationMenu     matlab.ui.control.DropDown
        text16              matlab.ui.control.Label
        calibration         matlab.ui.control.Label
        calibration_enable  matlab.ui.control.CheckBox
        temperature         matlab.ui.control.EditField
        spResolutionMenu    matlab.ui.control.DropDown
        nyquistMenu         matlab.ui.control.DropDown
        spResolution        matlab.ui.control.EditField
        text14              matlab.ui.control.Label
        nyquist             matlab.ui.control.EditField
        text13              matlab.ui.control.Label
        step                matlab.ui.control.EditField
        text12              matlab.ui.control.Label
        delay               matlab.ui.control.EditField
        text11              matlab.ui.control.Label
        pseudofrequency     matlab.ui.control.EditField
        text10              matlab.ui.control.Label
        pseudowavelength    matlab.ui.control.EditField
        text9               matlab.ui.control.Label
        laser               matlab.ui.control.EditField
        text8               matlab.ui.control.Label
        wavenumber          matlab.ui.control.EditField
        text7               matlab.ui.control.Label
        frequency           matlab.ui.control.EditField
        text6               matlab.ui.control.Label
        wavelength          matlab.ui.control.EditField
        text5               matlab.ui.control.Label
        text4               matlab.ui.control.Label
        apexAngle           matlab.ui.control.EditField
        crystalMenu         matlab.ui.control.DropDown
        text3               matlab.ui.control.Label
        text2               matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function WConversion_cryst_OpeningFcn(app, varargin)
            % --- Executes just before WConversion_cryst is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to WConversion_cryst (see VARARGIN)
            
            global c laser laser_frequency wavelength frequency wavenumber  ...
                   pseudofrequency pseudowavelength step delay nyquist ...
                   spectralResolution apexAngle temperature crystal Delta_n ...
                   calibration_button f_0 fr_real0 calibration_derivative ...
                   apodization_type Delta_f wedgeNum
            
            % Choose default command line output for WConversion_cryst
            handles.output = hObject;
            
            % Update handles structure
            c=299792458; %speed of light [m/s]
            
            %initialization of the pop-up menu
            set(handles.crystalMenu,'Value',1);
            set(handles.nyquistMenu,'Value',1);
            set(handles.spResolutionMenu,'Value',1);
            set(handles.apodizationMenu,'Value',1);
            app.WedgeNum.Value = 1;
            
            %initialization of the calibration button
            set(handles.calibration_enable,'Value',0);
            
            calibration_button=0; %no calibration loaded
            set(handles.calibration, 'String', '(none)'); %text in the GUI that indicates loaded calibration
            f_0=[]; fr_real0=[]; calibration_derivative=[]; %no calibration axis loaded
            
            temperature=23; %temperature initialization [°C]
            apexAngle=10; %wedge apex angle initialization [deg.]
            wedgeNum = 1; %factor in the delay formula when there are 2 wedges
            laser=532; %laser wavelength initialized [nm]
            laser_frequency=c/laser*1e-3;
            wavelength=600; %wavelength initialized [nm]
            frequency=c/wavelength*1e-3; %frequency initialization calculated from wavelength [THz]
            wavenumber=(laser_frequency-frequency)/c*1e10; %wavenumber initialization calculated from other parameters [cm-1]
            
            switch get(handles.crystalMenu,'Value')
                case 1 %YVO4
                    crystal=23; %number for YVO4 in nCryst function
                case 2 %alpha-BBO
                    crystal=42; %number for alpha-BBO in nCryst function
                case 3 %Calomel
                    crystal=47; %number of Calomel in nCryst function
            end
            
            Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
            
            pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %pseudowavelength initialization (optical cycle in term of wedge translation) [um]
            pseudofrequency=pseudowavelength^(-1); %pseudofrequency initialization [um-1]
            
            delay=5; %delay initialization [fs]
            step=delay/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-9; %step initialization calculated from the delay [um]
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            switch get(handles.apodizationMenu,'Value')
                case 1
                    apodization_type=0; %rectangular
                case 2
                    apodization_type=1; %Happ-Genzel
                case 3
                    apodization_type=2; %3-term Blackmann-Harris
                case 4
                    apodization_type=3; %4-term Blackmann-Harris
                case 5
                    apodization_type=4; %Triangular
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            set(handles.apexAngleEdit,'String',num2str(apexAngle));
            set(handles.temperatureEdit,'String',num2str(temperature));
            set(handles.wavelengthEdit,'String',num2str(wavelength));
            set(handles.frequencyEdit,'String',num2str(frequency));
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
            set(handles.laserEdit,'String',num2str(laser));
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.stepEdit,'String',num2str(step));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
            
            
            guidata(hObject, handles);
        end

        % Value changed function: apexAngle
        function apexAngle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to apexAngleEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle nyquist spectralResolution ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of apexAngleEdit as text
            %        str2double(get(hObject,'String')) returns contents of apexAngleEdit as a double
            
            %update the Edit window
            apexAngle=str2double(get(handles.apexAngleEdit,'String'));
            set(handles.apexAngleEdit,'String',apexAngle);
            
            pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
            pseudofrequency=pseudowavelength^(-1); %[um-1]
            
            delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: apodizationMenu
        function apodizationMenu_Callback(app, event)
            % --- Executes on selection change in apodizationMenu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to apodizationMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global apodization_type Delta_f c wavelength delay step spectralResolution
            % Hints: contents = cellstr(get(hObject,'String')) returns apodizationMenu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from apodizationMenu
            
            switch get(hObject,'Value')
                case 1
                    apodization_type=0; %rectangular
                case 2
                    apodization_type=1; %Happ-Genzel
                case 3
                    apodization_type=2; %3-term Blackmann-Harris
                case 4
                    apodization_type=3; %4-term Blackmann-Harris
                case 5
                    apodization_type=4; %Triangular
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: calibration_enable
        function calibration_enable_Callback(app, event)
            % --- Executes on button press in calibration_enable.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to calibration_enable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hint: get(hObject,'Value') returns toggle state of calibration_enable
            
            switch get(hObject,'Value')
                case 0 %button false (void)
                    calibration_button=0; %no calibration loaded
                    set(handles.calibration, 'String', '(none)'); %text in the GUI that indicates loaded calibration
            
                    %enable crystal pop-up menu, apex angle box and temperature box
                    set(handles.crystalMenu,'Enable','on');
                    set(handles.apexAngleEdit,'Enable','on');
                    set(handles.temperatureEdit,'Enable','on');
            
                    %update parameter according to TWINS theory (no calibration is
                    %loaded)
                    Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
                    pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
                    pseudofrequency=pseudowavelength^(-1); %[um-1]
                    delay=Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
                case 1 %button true (filled)
                    calibration_button=1; %calibration loaded
            
                    [file_calibration,path_calibration] = uigetfile('*.mat', 'Load Calibration'); %load calibration
                    filename_calibration=[path_calibration '\' file_calibration];
            
                    set(handles.calibration, 'String', file_calibration); %text in the GUI that indicates loaded calibration
            
                    input_calibration=load(filename_calibration);
                    f_0=input_calibration.f_0; %calibration pseudofrequencies axis [um^-1]
                    fr_real0=input_calibration.fr_real0; %calibration frequencies axis [THz]
                     uialert(app.figure1,strcat('Wavelength must be in the range',num2str(c*10^(-3)/max(fr_real0)),'-',num2str(c*10^(-3)/min(fr_real0)), 'nm.'), ...
                'Input Error', ...
                'Icon', 'warning');
                    calibration_derivative=zeros(size(fr_real0));
                    calibration_derivative(1:end-1)=diff(fr_real0)./diff(f_0);
                    calibration_derivative(end)=calibration_derivative(end-1);
            
                    %disable crystal pop-up menu, apex angle box and temperature box
                    set(handles.crystalMenu,'Enable','off');
                    set(handles.apexAngleEdit,'Enable','off');
                    set(handles.temperatureEdit,'Enable','off');
            
                    %update parameter according to experimental TWINS calibration (calibration is
                    %loaded)
                    pseudofrequency=interp1(fr_real0,f_0,frequency); %[um-1]
                    pseudowavelength=pseudofrequency^(-1); %[um-1]
                    delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.frequencyEdit,'String',num2str(frequency));
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: crystalMenu
        function crystalMenu_Callback(app, event)
            % --- Executes on selection change in crystalMenu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to crystalMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature nyquist spectralResolution ...
                   apodization_type wedgeNum
            % Hints: contents = cellstr(get(hObject,'String')) returns crystalMenu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from crystalMenu
            switch get(hObject,'Value')
                case 1 %YVO4
                    crystal=23; %number of YVO4 in nCryst function
                case 2 %alpha-BBO
                    crystal=42; %number of alpha-BBO in nCryst function
                case 3 %Calomel
                    crystal=47; %number of Calomel in nCryst function
            end
            
            Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
            
            pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
            pseudofrequency=pseudowavelength^(-1); %[um-1]
            
            delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: delay
        function delay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to delayEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of delayEdit as text
            %        str2double(get(hObject,'String')) returns contents of delayEdit as a double
            
            %update the Edit window
            delay=str2double(get(handles.delayEdit,'String'));
            set(handles.delayEdit,'String',delay);
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
            
                step=delay*c/(wedgeNum*Delta_n*sin(apexAngle/180*pi))*1e-9; %[um]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
            
                step=interp1(f_0,calibration_derivative,pseudofrequency).*delay.*1e-3; %[um] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.stepEdit,'String',num2str(step));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: frequency
        function frequency_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to frequencyEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of frequencyEdit as text
            %        str2double(get(hObject,'String')) returns contents of frequencyEdit as a double
            
            %update the Edit window
            frequency=str2num(get(handles.frequencyEdit,'String'));
            set(handles.frequencyEdit,'String',frequency);
            
            wavelength=c/frequency*1e-3; %[nm]
            wavenumber=(laser_frequency-frequency)/c*1e10; %[cm-1]
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
                pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
                pseudofrequency=pseudowavelength^(-1); %[um-1]
            
                delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
                pseudofrequency=interp1(fr_real0,f_0,frequency); %[um-1]
                pseudowavelength=pseudofrequency^(-1); %[um-1]
            
                delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.wavelengthEdit,'String',num2str(wavelength));
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: laser
        function laser_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to laserEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber laser
            % Hints: get(hObject,'String') returns contents of laserEdit as text
            %        str2double(get(hObject,'String')) returns contents of laserEdit as a double
            
            %update the Edit window
            laser=str2double(get(handles.laserEdit,'String'));
            set(handles.laserEdit,'String',laser);
            
            laser_frequency=c/laser*1e-3; %[THz]
            wavenumber=(laser_frequency-frequency)/c*1e10; %[cm-1]
            
            %update changed parameters
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
        end

        % Value changed function: nyquist
        function nyquist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nyquistEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of nyquistEdit as text
            %        str2double(get(hObject,'String')) returns contents of nyquistEdit as a double
            
            %update the Edit window
            nyquist=str2num(get(handles.nyquistEdit,'String'));
            set(handles.nyquistEdit,'String',nyquist);
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    step=1/(2*nyquist); %[um]
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
                case 2 %frequency [THz]
                    delay=1/(2*nyquist)*1e3;
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        step=delay/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-9; %[um]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        step=interp1(f_0,calibration_derivative,pseudofrequency).*delay.*1e-3; %[um] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
                case 3 %wavelength [nm]
                    delay=1/(2*(c/nyquist*1e-3))*1e3;
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        step=delay/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-9; %[um]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        step=interp1(f_0,calibration_derivative,pseudofrequency).*delay.*1e-3; %[um] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.stepEdit,'String',num2str(step));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: nyquistMenu
        function nyquistMenu_Callback(app, event)
            % --- Executes on selection change in nyquistMenu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nyquistMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature nyquist spectralResolution
            % Hints: contents = cellstr(get(hObject,'String')) returns nyquistMenu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from nyquistMenu
            
            switch get(hObject,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            %update changed parameters
            set(handles.nyquistEdit,'String',num2str(nyquist));
        end

        % Value changed function: pseudofrequency
        function pseudofrequency_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pseudofrequencyEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of pseudofrequencyEdit as text
            %        str2double(get(hObject,'String')) returns contents of pseudofrequencyEdit as a double
            
            %update the Edit window
            pseudofrequency=str2double(get(handles.pseudofrequencyEdit,'String'));
            set(handles.pseudofrequencyEdit,'String',pseudofrequency);
            
            pseudowavelength=pseudofrequency^(-1); %[um]
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                %WAVELENGTH AS FUNCTION OF THE PSEUDOWAVELENGTH (OPTICAL CYCLE IN um)
                %wavelength=Delta_n*sin(apexAngle)*pseudowavelength
                %as Delta_n depends on wavelength a numerical method to solve this equation
                %in wavelength is necessary
                %We solve f(wavelength)=0 with the Newton method
                f=@(variable) variable-abs(diff(nCryst(crystal,variable*1e-3,temperature))).*sin(apexAngle/180*pi).*pseudowavelength.*1e3; %[nm]
                initialGuess=wedgeNum*Delta_n.*sin(apexAngle/180*pi).*pseudowavelength.*1e3; %initial guess [nm] exploiting the actual Delta_n
                iterations=100; %maximum number of iterations in the method
                tollerance=1e-5; %error tollerance [nm]
                wavelength=newton_method(f,initialGuess,iterations,tollerance); %[nm]
            
                frequency=c/wavelength*1e-3; %[THz]
            
                Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
            
                delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
                frequency=interp1(f_0,fr_real0,pseudofrequency); %[THz]
                wavelength=c/frequency*1e-3; %[nm]
            
                delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            end
            
            wavenumber=(laser_frequency-frequency)/c*1e10; %[cm-1]
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.wavelengthEdit,'String',num2str(wavelength));
            set(handles.frequencyEdit,'String',num2str(frequency));
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: pseudowavelength
        function pseudowavelength_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pseudowavelengthEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of pseudowavelengthEdit as text
            %        str2double(get(hObject,'String')) returns contents of pseudowavelengthEdit as a double
            
            %update the Edit window
            pseudowavelength=str2double(get(handles.pseudowavelengthEdit,'String'));
            set(handles.pseudowavelengthEdit,'String',pseudowavelength);
            
            pseudofrequency=pseudowavelength^(-1); %[um-1]
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                %WAVELENGTH AS FUNCTION OF THE PSEUDOWAVELENGTH (OPTICAL CYCLE IN um)
                %wavelength=Delta_n*sin(apexAngle)*pseudowavelength
                %as Delta_n depends on wavelength a numerical method to solve this equation
                %in wavelength is necessary
                %We solve f(wavelength)=0 with the Newton method
                f=@(variable) variable-abs(diff(nCryst(crystal,variable*1e-3,temperature))).*sin(apexAngle/180*pi).*pseudowavelength.*1e3; %[nm]
                initialGuess=wedgeNum*Delta_n.*sin(apexAngle/180*pi).*pseudowavelength.*1e3; %initial guess [nm] exploiting the actual Delta_n
                iterations=100; %maximum number of iterations in the method
                tollerance=1e-5; %error tollerance [nm]
                wavelength=newton_method(f,initialGuess,iterations,tollerance); %[nm]
            
                frequency=c/wavelength*1e-3; %[THz]
            
                Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
            
                delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
                frequency=interp1(f_0,fr_real0,pseudofrequency); %[THz]
                wavelength=c/frequency*1e-3; %[nm]
            
                delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            end
            
            
            wavenumber=(laser_frequency-frequency)/c*1e10; %[cm-1]
            
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.wavelengthEdit,'String',num2str(wavelength));
            set(handles.frequencyEdit,'String',num2str(frequency));
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: spResolution
        function spResolution_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to spResolutionEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of spResolutionEdit as text
            %        str2double(get(hObject,'String')) returns contents of spResolutionEdit as a double
            
            %update the Edit window
            spectralResolution=str2double(get(handles.spResolutionEdit,'String'));
            set(handles.spResolutionEdit,'String',spectralResolution);
            
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    Delta_f=spectralResolution; %[THz]
            
                    switch apodization_type
                        case 0 %rectangular apodization
                            delay=1.2043./Delta_f.*1e3; %[fs] %the conversion factor 1.2043 has been calculated in Matlab
                        case 1 %Happ-Genzel apodization
                            delay=1.8152./Delta_f.*1e3; %[fs] %the conversion factor 1.8152 has been calculated in Matlab
                        case 2 %3-term Blackmann-Harris
                            delay=2.2694./Delta_f.*1e3; %[fs] %the conversion factor 2.2694 has been calculated in Matlab
                        case 3 %4-term Blackmann-Harris
                            delay=2.6611./Delta_f.*1e3; %[fs] %the conversion factor 2.6611 has been calculated in Matlab
                        case 4 %Trinagular
                            delay=1.7683./Delta_f.*1e3; %[fs] %the conversion factor 1.7683 has been calculated in Matlab
                    end
            
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        step=delay/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-9; %[um]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        step=interp1(f_0,calibration_derivative,pseudofrequency).*delay.*1e-3; %[um] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
            
                case 2 %wavelength [nm]
                    Delta_f=c/wavelength*spectralResolution/(wavelength-spectralResolution)*1e-3; %[THz]
            
                    switch apodization_type
                        case 0 %rectangular apodization
                            delay=1.2043./Delta_f.*1e3; %[fs] %the conversion factor 1.2043 has been calculated in Matlab
                        case 1 %Happ-Genzel apodization
                            delay=1.8152./Delta_f.*1e3; %[fs] %the conversion factor 1.8152 has been calculated in Matlab
                        case 2 %3-term Blackmann-Harris
                            delay=2.2694./Delta_f.*1e3; %[fs] %the conversion factor 2.2694 has been calculated in Matlab
                        case 3 %4-term Blackmann-Harris
                            delay=2.6611./Delta_f.*1e3; %[fs] %the conversion factor 2.6611 has been calculated in Matlab
                        case 4 %Trinagular
                            delay=1.7683./Delta_f.*1e3; %[fs] %the conversion factor 1.7683 has been calculated in Matlab
                    end
            
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        step=delay/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-9; %[um]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        step=interp1(f_0,calibration_derivative,pseudofrequency).*delay.*1e-3; %[um] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
                case 3 %wavenumber [cm-1]
                    Delta_f=spectralResolution*c*1e-10; %[THz]
            
                    switch apodization_type
                        case 0 %rectangular apodization
                            delay=1.2043./Delta_f.*1e3; %[fs] %the conversion factor 1.2043 has been calculated in Matlab
                        case 1 %Happ-Genzel apodization
                            delay=1.8152./Delta_f.*1e3; %[fs] %the conversion factor 1.8152 has been calculated in Matlab
                        case 2 %3-term Blackmann-Harris
                            delay=2.2694./Delta_f.*1e3; %[fs] %the conversion factor 2.2694 has been calculated in Matlab
                        case 3 %4-term Blackmann-Harris
                            delay=2.6611./Delta_f.*1e3; %[fs] %the conversion factor 2.6611 has been calculated in Matlab
                        case 4 %Trinagular
                            delay=1.7683./Delta_f.*1e3; %[fs] %the conversion factor 1.7683 has been calculated in Matlab
                    end
            
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        step=delay/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-9; %[um]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        step=interp1(f_0,calibration_derivative,pseudofrequency).*delay.*1e-3; %[um] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
                case 4 %pseudofrequency [um^[-1}]
                            switch apodization_type
                        case 0 %rectangular apodization
                            step=1.2043./spectralResolution; %[um] %the conversion factor 1.2043 has been calculated in Matlab
                        case 1 %Happ-Genzel apodization
                            step=1.8152./spectralResolution; %[um] %the conversion factor 1.8152 has been calculated in Matlab
                        case 2 %3-term Blackmann-Harris
                            step=2.2694./spectralResolution; %[um] %the conversion factor 2.2694 has been calculated in Matlab
                        case 3 %4-term Blackmann-Harris
                            step=2.6611./spectralResolution; %[um] %the conversion factor 2.6611 has been calculated in Matlab
                        case 4 %Trinagular
                            step=1.7683./spectralResolution; %[um] %the conversion factor 1.7683 has been calculated in Matlab
                    end
            
                    if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                        delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
                    else %if calibration is loaded: calculation using experimental TWINS calibration
                        delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
                    end
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            %update changed parameters
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.stepEdit,'String',num2str(step));
            set(handles.nyquistEdit,'String',num2str(nyquist));
        end

        % Value changed function: spResolutionMenu
        function spResolutionMenu_Callback(app, event)
            % --- Executes on selection change in spResolutionMenu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to spResolutionMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature nyquist spectralResolution ...
                   apodization_type
            % Hints: contents = cellstr(get(hObject,'String')) returns spResolutionMenu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from spResolutionMenu
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(hObject,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=1/step;
            end
            
            %update changed parameters
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: step
        function step_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stepEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of stepEdit as text
            %        str2double(get(hObject,'String')) returns contents of stepEdit as a double
            
            %update the Edit window
            step=str2double(get(handles.stepEdit,'String'));
            set(handles.stepEdit,'String',step);
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
            
                delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
            
                delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: temperature
        function temperature_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to temperatureEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature nyquist spectralResolution ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of temperatureEdit as text
            %        str2double(get(hObject,'String')) returns contents of temperatureEdit as a double
            
            %update the Edit window
            temperature=str2double(get(handles.temperatureEdit,'String'));
            set(handles.temperatureEdit,'String',temperature);
            
            Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
            
            pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
            pseudofrequency=pseudowavelength^(-1); %[um-1]
            
            delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: wavelength
        function wavelength_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to wavelengthEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button f_0 fr_real0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of wavelengthEdit as text
            %        str2double(get(hObject,'String')) returns contents of wavelengthEdit as a double
            
            %update the Edit window
            wavelength=str2num(get(handles.wavelengthEdit,'String'));
            set(handles.wavelengthEdit,'String',wavelength);
            
            frequency=c/wavelength*1e-3; %[THz]
            wavenumber=(laser_frequency-frequency)/c*1e10; %[cm-1]
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
                pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
                pseudofrequency=pseudowavelength^(-1); %[um-1]
            
                delay=Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
                pseudofrequency=interp1(fr_real0,f_0,frequency); %[um-1]
                pseudowavelength=pseudofrequency^(-1); %[um-1]
            
                delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.frequencyEdit,'String',num2str(frequency));
            set(handles.wavenumberEdit,'String',num2str(wavenumber));
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: wavenumber
        function wavenumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to wavenumberEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global crystal pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle temperature laser_frequency ...
                   wavenumber nyquist spectralResolution ...
                   calibration_button fr_real0 f_0 calibration_derivative ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of wavenumberEdit as text
            %        str2double(get(hObject,'String')) returns contents of wavenumberEdit as a double
            
            %update the Edit window
            wavenumber=str2num(get(handles.wavenumberEdit,'String'));
            set(handles.wavenumberEdit,'String',wavenumber);
            
            frequency=laser_frequency-wavenumber*c*1e-10; %[THz]
            wavelength=c/frequency*1e-3; %[nm]
            
            if calibration_button==0 %if calibration is not loaded: calculation using TWINS theory formula
                Delta_n=abs(diff(nCryst(crystal,wavelength.*1e-3,temperature)));
                pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
                pseudofrequency=pseudowavelength^(-1); %[um-1]
            
                delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            else %if calibration is loaded: calculation using experimental TWINS calibration
                pseudofrequency=interp1(fr_real0,f_0,frequency); %[um-1]
                pseudowavelength=pseudofrequency^(-1); %[um-1]
            
                delay=interp1(f_0,calibration_derivative,pseudofrequency).^(-1).*step.*1e3; %[fs] deltaTau=(d CALIBRATION / d PSEUDOFREQUENCY)^(-1)*deltaX
            end
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.wavelengthEdit,'String',num2str(wavelength));
            set(handles.frequencyEdit,'String',num2str(frequency));
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end

        % Value changed function: WedgeNum
        function WedgeNumValueChanged(app, event)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to apexAngleEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global pseudofrequency pseudowavelength wavelength frequency ...
                   step delay Delta_n c apexAngle nyquist spectralResolution ...
                   apodization_type wedgeNum
            % Hints: get(hObject,'String') returns contents of apexAngleEdit as text
            %        str2double(get(hObject,'String')) returns contents of apexAngleEdit as a double
            
            %update the Edit window
            wedgeNum=app.WedgeNum.Value;
            
            pseudowavelength=(1/frequency)/(wedgeNum*Delta_n/c*sin(apexAngle/180*pi))*1e-6; %optical cycle [um]
            pseudofrequency=pseudowavelength^(-1); %[um-1]
            
            delay=wedgeNum*Delta_n/c*sin(apexAngle/180*pi)*step*1e9; %[fs]
            
            switch get(handles.nyquistMenu,'Value')
                case 1 %pseudofrequency [um^[-1}]
                    nyquist=1/(2*step);
                case 2 %frequency [THz]
                    nyquist=1/(2*delay)*1e3;
                case 3 %wavelength [nm]
                    nyquist=c/(1/(2*delay)*1e3)*1e-3;
            end
            
            Delta_f=FWHM_apodization(apodization_type,delay).*1e3; %spectral resolution in THz
            switch get(handles.spResolutionMenu,'Value')
                case 1 %frequency [THz]
                    spectralResolution=Delta_f;
                case 2 %wavelength [nm]
                    spectralResolution=(Delta_f/c*1e3)/(1+Delta_f/c*1e3)*wavelength^2;
                case 3 %wavenumber [cm-1]
                    spectralResolution=Delta_f/c*1e10;
                case 4 %pseudofrequency [um^[-1}]
                    spectralResolution=FWHM_apodization(apodization_type,step);
            end
            
            %update changed parameters
            set(handles.pseudowavelengthEdit,'String',num2str(pseudowavelength));
            set(handles.pseudofrequencyEdit,'String',num2str(pseudofrequency));
            set(handles.delayEdit,'String',num2str(delay));
            set(handles.nyquistEdit,'String',num2str(nyquist));
            set(handles.spResolutionEdit,'String',num2str(spectralResolution));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'WConversion_cryst';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Crystal';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'wedge apex angle [deg.]';
            app.crystalMenu = uidropdown(app.figure1);
            app.crystalMenu.Items = {'YVO4', 'alpha-BBO', 'Calomel'};
            app.crystalMenu.ValueChangedFcn = createCallbackFcn(app, @crystalMenu_Callback, true);
            app.crystalMenu.Tag = 'crystalMenu';
            app.crystalMenu.Value = 'YVO4';
            app.apexAngle = uieditfield(app.figure1, 'text');
            app.apexAngle.ValueChangedFcn = createCallbackFcn(app, @apexAngle_Callback, true);
            app.apexAngle.Tag = 'apexAngleEdit';
            app.apexAngle.Value = 'ApexAngle';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'temperature [°C]';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'wavelength [nm]';
            app.wavelength = uieditfield(app.figure1, 'text');
            app.wavelength.ValueChangedFcn = createCallbackFcn(app, @wavelength_Callback, true);
            app.wavelength.Tag = 'wavelengthEdit';
            app.wavelength.Value = 'Wavelength';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'frequency [THz]';
            app.frequency = uieditfield(app.figure1, 'text');
            app.frequency.ValueChangedFcn = createCallbackFcn(app, @frequency_Callback, true);
            app.frequency.Tag = 'frequencyEdit';
            app.frequency.Value = 'Frequency';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'wavenumber [cm^{-1}]';
            app.wavenumber = uieditfield(app.figure1, 'text');
            app.wavenumber.ValueChangedFcn = createCallbackFcn(app, @wavenumber_Callback, true);
            app.wavenumber.Tag = 'wavenumberEdit';
            app.wavenumber.Value = 'Wavenumber';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'excitation laser [nm]';
            app.laser = uieditfield(app.figure1, 'text');
            app.laser.ValueChangedFcn = createCallbackFcn(app, @laser_Callback, true);
            app.laser.Tag = 'laserEdit';
            app.laser.Value = 'Laser';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'pseudowavelength [um]';
            app.pseudowavelength = uieditfield(app.figure1, 'text');
            app.pseudowavelength.ValueChangedFcn = createCallbackFcn(app, @pseudowavelength_Callback, true);
            app.pseudowavelength.Tag = 'pseudowavelengthEdit';
            app.pseudowavelength.Value = 'Pseudowavelength';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'pseudofrequency [um^{-1}]';
            app.pseudofrequency = uieditfield(app.figure1, 'text');
            app.pseudofrequency.ValueChangedFcn = createCallbackFcn(app, @pseudofrequency_Callback, true);
            app.pseudofrequency.Tag = 'pseudofrequencyEdit';
            app.pseudofrequency.Value = 'Pseudofrequency';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'delay [fs]';
            app.delay = uieditfield(app.figure1, 'text');
            app.delay.ValueChangedFcn = createCallbackFcn(app, @delay_Callback, true);
            app.delay.Tag = 'delayEdit';
            app.delay.Value = 'DelayEdit';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'step/scan [um]';
            app.step = uieditfield(app.figure1, 'text');
            app.step.ValueChangedFcn = createCallbackFcn(app, @step_Callback, true);
            app.step.Tag = 'stepEdit';
            app.step.Value = 'Step';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Nyquist limit';
            app.nyquist = uieditfield(app.figure1, 'text');
            app.nyquist.ValueChangedFcn = createCallbackFcn(app, @nyquist_Callback, true);
            app.nyquist.Tag = 'nyquistEdit';
            app.nyquist.Value = 'Nyquist';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'spectral resolution';
            app.spResolution = uieditfield(app.figure1, 'text');
            app.spResolution.ValueChangedFcn = createCallbackFcn(app, @spResolution_Callback, true);
            app.spResolution.Tag = 'spResolutionEdit';
            app.spResolution.Value = 'SpResolution';
            app.nyquistMenu = uidropdown(app.figure1);
            app.nyquistMenu.Items = {'pseudofrequency [um^{-1}]', 'frequency [THz]', 'wavelength [nm]'};
            app.nyquistMenu.ValueChangedFcn = createCallbackFcn(app, @nyquistMenu_Callback, true);
            app.nyquistMenu.Tag = 'nyquistMenu';
            app.nyquistMenu.Value = 'pseudofrequency [um^{-1}]';
            app.spResolutionMenu = uidropdown(app.figure1);
            app.spResolutionMenu.Items = {'frequency [THz]', 'wavelength [nm]', 'wavenumber [cm^{-1}]', 'pseudofrequency [um^{-1}]'};
            app.spResolutionMenu.ValueChangedFcn = createCallbackFcn(app, @spResolutionMenu_Callback, true);
            app.spResolutionMenu.Tag = 'spResolutionMenu';
            app.spResolutionMenu.Value = 'frequency [THz]';
            app.temperature = uieditfield(app.figure1, 'text');
            app.temperature.ValueChangedFcn = createCallbackFcn(app, @temperature_Callback, true);
            app.temperature.Tag = 'temperatureEdit';
            app.temperature.Value = 'Temperature';
            app.calibration_enable = uicheckbox(app.figure1);
            app.calibration_enable.ValueChangedFcn = createCallbackFcn(app, @calibration_enable_Callback, true);
            app.calibration_enable.Tag = 'calibration_enable';
            app.calibration_enable.Text = 'TWINS Calibration';
            app.calibration = uilabel(app.figure1);
            app.calibration.Tag = 'calibration';
            app.calibration.Text = '(none)';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Interferogram Apodization';
            app.apodizationMenu = uidropdown(app.figure1);
            app.apodizationMenu.Items = {'(none)', 'Happ-Genzel', '3-term Blackmann-Harris', '4-term Blackmann-Harris', 'Triangular'};
            app.apodizationMenu.ValueChangedFcn = createCallbackFcn(app, @apodizationMenu_Callback, true);
            app.apodizationMenu.Tag = 'apodizationMenu';
            app.apodizationMenu.Value = '(none)';
            app.WedgeNum = uidropdown(app.figure1);
            app.WedgeNum.Items = {'2', '4'};
            app.WedgeNum.ItemsData = [1 2];
            app.WedgeNum.ValueChangedFcn = createCallbackFcn(app, @WedgeNumValueChanged, true);
            app.WedgeNum.Value = 1;
            app.text3_2 = uilabel(app.figure1);
            app.text3_2.Text = 'wedge number';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WConversion_cryst_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)WConversion_cryst_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end